classdef AssignmentGUI_Video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup3                       matlab.ui.container.TabGroup
        PathSettingsTab                 matlab.ui.container.Tab
        ChoosecoordinatesLabel          matlab.ui.control.Label
        PathfindingoptionsLabel         matlab.ui.control.Label
        SelectpathfindingmethodLabel    matlab.ui.control.Label
        RolliingaverageredlineLabel     matlab.ui.control.Label
        TimetocalculateeachiterationbluelineLabel  matlab.ui.control.Label
        PathStatisticsPanel             matlab.ui.container.Panel
        NumberofnodesEditField          matlab.ui.control.NumericEditField
        NumberofnodesEditFieldLabel     matlab.ui.control.Label
        Total_path_time_2               matlab.ui.control.NumericEditField
        TotalpathfindtimeLabel_2        matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        RRTRapidlyexploringRandomTreeCheckBox  matlab.ui.control.CheckBox
        PRMProbabilisticRoadMapCheckBox  matlab.ui.control.CheckBox
        GAGeneticAlgorithmCheckBox      matlab.ui.control.CheckBox
        End_location_x                  matlab.ui.control.NumericEditField
        EndlocationLabel_2              matlab.ui.control.Label
        Start_location_x                matlab.ui.control.NumericEditField
        StartlocationLabel_2            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PRMTab                          matlab.ui.container.Tab
        num_of_repeat_PRM               matlab.ui.control.NumericEditField
        numberofrepeatsLabel            matlab.ui.control.Label
        connection_Distance_PRM         matlab.ui.control.NumericEditField
        connectiondistanceLabel_2       matlab.ui.control.Label
        node_increasee_PRM              matlab.ui.control.NumericEditField
        nodeincreaseLabel               matlab.ui.control.Label
        final_num_nodess_PRM            matlab.ui.control.NumericEditField
        finalnumberofnodesLabel         matlab.ui.control.Label
        inital_num_nodess_PRM           matlab.ui.control.NumericEditField
        initalnumberofnodesLabel        matlab.ui.control.Label
        RRTTab                          matlab.ui.container.Tab
        MaxNumofTreeNodes               matlab.ui.control.NumericEditField
        maxnumberoftreenodesLabel       matlab.ui.control.Label
        number_of_repeats               matlab.ui.control.NumericEditField
        numberofrepeatsLabel_2          matlab.ui.control.Label
        finish_maxIterations            matlab.ui.control.NumericEditField
        finishmaxiterationsLabel        matlab.ui.control.Label
        connection_distance             matlab.ui.control.NumericEditField
        connectiondistanceLabel         matlab.ui.control.Label
        increase_maxIterations          matlab.ui.control.NumericEditField
        maxiterationsincreaseLabel      matlab.ui.control.Label
        start_maxIterations             matlab.ui.control.NumericEditField
        startmaxiterationsLabel         matlab.ui.control.Label
        GATab_2                         matlab.ui.container.Tab
        Number_of_dots_to_progress      matlab.ui.control.NumericEditField
        NumberofdotstoprogressLabel     matlab.ui.control.Label
        Max_number_of_movements         matlab.ui.control.NumericEditField
        MaxnumberofmovementsLabel       matlab.ui.control.Label
        Chance_of_mutation              matlab.ui.control.NumericEditField
        ChanceofmutationLabel_2         matlab.ui.control.Label
        Generations                     matlab.ui.control.NumericEditField
        GenerationsLabel                matlab.ui.control.Label
        Number_of_dots                  matlab.ui.control.NumericEditField
        NumberofdotsLabel               matlab.ui.control.Label
        ShowpointsButton                matlab.ui.control.Button
        End_location_y                  matlab.ui.control.NumericEditField
        Start_location_y                matlab.ui.control.NumericEditField
        PlanPathButton                  matlab.ui.control.Button
        SelectMapDropDown               matlab.ui.control.DropDown
        SelectMapDropDownLabel          matlab.ui.control.Label
        Time_taken                      matlab.ui.control.UIAxes
        Start_end_points                matlab.ui.control.UIAxes
        PathPerformanceTab              matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        PRMTab_2                        matlab.ui.container.Tab
        lowest_distance_value_PRM_field  matlab.ui.control.NumericEditField
        lowest_distance_valueLabel      matlab.ui.control.Label
        lowest_distance_node_PRM_field  matlab.ui.control.NumericEditField
        lowest_distance_nodeLabel       matlab.ui.control.Label
        lowest_feasible_distance_PRM_field  matlab.ui.control.NumericEditField
        lowest_feasible_distanceLabel   matlab.ui.control.Label
        lowest_feasible_node_PRM_field  matlab.ui.control.NumericEditField
        lowest_feasible_nodeLabel       matlab.ui.control.Label
        Average_distance_at_nodes_no_zero_PRM_axis  matlab.ui.control.UIAxes
        Average_distance_at_nodes_PRM_axis  matlab.ui.control.UIAxes
        Shortest_Path_PRM_axis          matlab.ui.control.UIAxes
        First_Feasible_Path_PRM_axis    matlab.ui.control.UIAxes
        RRTTab_2                        matlab.ui.container.Tab
        lowest_distance_value_RRT_field  matlab.ui.control.NumericEditField
        lowest_distance_valueLabel_2    matlab.ui.control.Label
        lowest_distance_node_RRT_field  matlab.ui.control.NumericEditField
        lowest_distance_nodeLabel_2     matlab.ui.control.Label
        lowest_feasible_distance_RRT_field  matlab.ui.control.NumericEditField
        lowest_feasible_distanceLabel_2  matlab.ui.control.Label
        lowest_feasible_node_RRT_field  matlab.ui.control.NumericEditField
        lowest_feasible_nodeLabel_2     matlab.ui.control.Label
        Average_distance_at_nodes_no_zero_RRT_axis  matlab.ui.control.UIAxes
        Average_distance_at_nodes_RRT_axis  matlab.ui.control.UIAxes
        Shortest_Path_RRT_axis          matlab.ui.control.UIAxes
        First_Feasible_Path_RRT_axis    matlab.ui.control.UIAxes
        GATab                           matlab.ui.container.Tab
        avg_shortest_distance_top_dots  matlab.ui.control.UIAxes
        shortest_distance_top_dot       matlab.ui.control.UIAxes
        Shortest_path                   matlab.ui.control.UIAxes
        avg_distance_top_dots           matlab.ui.control.UIAxes
        distance_top_dot                matlab.ui.control.UIAxes
        avg_distance                    matlab.ui.control.UIAxes
        RoboticsTab                     matlab.ui.container.Tab
        SelectpathDropDown              matlab.ui.control.DropDown
        SelectpathDropDownLabel         matlab.ui.control.Label
        Select_robot_DropDown           matlab.ui.control.DropDown
        SelectrobotLabel                matlab.ui.control.Label
        Plot_robot_path                 matlab.ui.control.Button
        Map1                            matlab.ui.control.UIAxes
        Map3                            matlab.ui.control.UIAxes
        Map2                            matlab.ui.control.UIAxes
        AllrobotsperformanceTab         matlab.ui.container.Tab
        BicycleblueCheckBox             matlab.ui.control.CheckBox
        UnicycleredCheckBox             matlab.ui.control.CheckBox
        DifferentialdrivegreenCheckBox  matlab.ui.control.CheckBox
        CalculateRobotspathsButton      matlab.ui.control.Button
        All_path_comparison2            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        occupancyMatrix
        emptyMap
        non_inflate_map
        robotRadius
        startLocation
        endLocation
        map
        lowest_feasible_node_RRT
        lowest_feasible_distance_RRT
        first_feasible_path_RRT
        lowest_distance_node_RRT
        lowest_distance_value_RRT
        shortest_path_RRT

        start_MaxIterations_RRT
        finish_MaxIterations_RRT
        increase_MaxIterations_RRT
        MaxNumTreeNodes_RRT
        max_connection_distance_RRT
        num_of_repeats_RRT
        type_of_selection

        distance_matrix_RRT
        non_zero_matrix_RRT
        distance_mean_RRT
        no_zero_distance_mean_RRT
        graph_data_RRT

        distance_data_matrix_PRM

        lowest_feasible_node_PRM
        lowest_feasible_distance_PRM
        first_feasible_path_PRM
        lowest_distance_node_PRM
        lowest_distance_value_PRM
        shortest_path_PRM

        inital_num_nodes_PRM
        final_num_nodes_PRM
        node_increase_PRM
        connection_distance_PRM
        num_of_repeats_PRM
        type_of_selection_PRM
        distance_matrix_PRM
        non_zero_matrix_PRM
        distance_mean_PRM
        no_zero_distance_mean_PRM
        graph_data_PRM

        currentRobot
        controller

        path
        shortest_Path_genetic
    end

    methods (Access = private)

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlanPathButton
        function PlanPathButtonPushed(app, event)
            app.startLocation = [app.Start_location_x.Value app.Start_location_y.Value];
            app.endLocation = [app.End_location_x.Value app.End_location_y.Value];
            total_time=0;
            % 1 for average, 2 for lowest values
            app.type_of_selection = 1;

            if app.PRMProbabilisticRoadMapCheckBox.Value == 1

                % Probabilistic Roadmap PRM

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %clear graphs
                cla(app.Average_distance_at_nodes_PRM_axis)
                cla(app.Average_distance_at_nodes_no_zero_PRM_axis)

                %initalise values to values set in numerical input boxes
                app.inital_num_nodes_PRM = app.inital_num_nodess_PRM.Value;
                app.final_num_nodes_PRM = app.final_num_nodess_PRM.Value;
                app.node_increase_PRM = app.node_increasee_PRM.Value;

                app.connection_distance_PRM = app.connection_Distance_PRM.Value;
                app.num_of_repeats_PRM = app.num_of_repeat_PRM.Value;

                % For loop used for establishing the robot motion and creating the map
                sampleTime = 0.001;            % Sample time [s]

                %set rate to iterate
                rateofit = rateControl(1/sampleTime);

                app.robotRadius = 0.2;

                % Create PRM object referring to this graph
                prm1 = mobileRobotPRM;
                prm1.Map = app.map;
                prm1.NumNodes = app.inital_num_nodes_PRM;
                prm1.ConnectionDistance = app.connection_distance_PRM;

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %Presetting values needed for calculations
                iteration_count = 0;

                app.first_feasible_path_PRM = 0;
                app.lowest_feasible_distance_PRM = 0;

                app.lowest_distance_value_PRM = 0;
                app.shortest_path_PRM = 0;

                current_iteration = 0;
                non_zero_count = 0;
                %Calculating the amount of iterations required
                iterations = (app.final_num_nodes_PRM-app.inital_num_nodes_PRM)/app.node_increase_PRM;
                repeats = app.num_of_repeats_PRM;
                distance_matrix = zeros(iterations,2);
                repeat_matrix = zeros(repeats,1);
                non_zero_matrix = zeros(iterations,2);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % main code to produce paths

                for iterations = 1:iterations
                    tic
                    % Increment and display the iteration count
                    iteration_count = iteration_count + 1;
                    %                     disp(['Performing iteration ' num2str(iteration_count) '...'])
                    app.CurrentIterationEditField.Value = iterations;

                    % No feasible path found yet, increase the number of nodes
                    prm1.NumNodes = prm1.NumNodes + app.node_increase_PRM;
                    %                     disp(['Number of nodes = ' num2str(prm1.NumNodes) '...'])
                    app.NumberofnodesEditField.Value = prm1.NumNodes;
                    % Repeat for each num of nodes to increase chance of path
                    for repeats =1:repeats

                        % Update PRM road map with new number of nodes
                        update(prm1);
                        % Find path on new PRM roadmap
                        app.path = findpath(prm1, app.startLocation, app.endLocation);

                        show(prm1, 'Parent', app.Start_end_points)
                        hold(app.Start_end_points,'on');
                        scatter(app.Start_end_points,app.startLocation(1), app.startLocation(2), 'filled', 'r')
                        scatter(app.Start_end_points,app.endLocation(1), app.endLocation(2), 'filled', 'g')
                        title(app.Start_end_points,'Shortest Path Through Probabilistic Roadmap')
                        hold(app.Start_end_points,'off');

                        % Wait to iterate at the proper rate
                        waitfor(rateofit);

                        %Calculate the distance of the path
                        [distance] = Path_length_func(app.path);

                        % Store node number and distance in a matrix
                        distance_matrix(iterations,1)= prm1.NumNodes;
                        distance_matrix(iterations,2)= distance;

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        % If there is no path then display no path
                        if distance == 0
                            %             disp('no path')

                            % If there is a path then...
                        else
                            if distance > 0
                                %                 disp('path')

                                %setting the count of the non zero matrix
                                if iterations ~= current_iteration
                                    current_iteration = iterations;
                                    non_zero_count = non_zero_count + 1;
                                end
                                if non_zero_count == 1
                                    app.lowest_feasible_node_PRM = prm1.NumNodes;
                                    app.lowest_feasible_distance_PRM = distance;
                                    app.first_feasible_path_PRM = app.path;
                                    disp(total_time)

                                    show(app.map,'Parent',app.First_Feasible_Path_PRM_axis);
                                    hold(app.First_Feasible_Path_PRM_axis,'on');
                                    plot(app.First_Feasible_Path_PRM_axis,app.first_feasible_path_PRM(:,1),app.first_feasible_path_PRM(:,2), 'o-');
                                    title(app.First_Feasible_Path_PRM_axis,'First Feasible Path')
                                    hold(app.First_Feasible_Path_PRM_axis,'off');

                                end
                                %If first repeat then set matrix value to value
                                if non_zero_matrix(non_zero_count,:) == 0
                                    non_zero_matrix(non_zero_count,:) = distance;

                                end

                                % Compare new matrix value to old value
                                if distance <= non_zero_matrix(non_zero_count,:)
                                    % If value is smaller replace value in matrix
                                    non_zero_matrix(non_zero_count,1) = prm1.NumNodes;
                                    non_zero_matrix(non_zero_count,2) = distance;

                                    % Set lowest distance value to the lowest in the matrix
                                    % so far
                                    app.lowest_distance_value_PRM = min(non_zero_matrix(1:non_zero_count,2));
                                    %Compare current value to lowest value in non zero matrix
                                    if distance == app.lowest_distance_value_PRM
                                        %Set shortest path to the current path
                                        app.shortest_path_PRM = app.path;
                                        app.lowest_distance_node_PRM = prm1.NumNodes;
                                        disp(total_time)
                                        show(prm1, 'Parent', app.Shortest_Path_PRM_axis)
                                        hold(app.Shortest_Path_PRM_axis,'on');
                                        scatter(app.Shortest_Path_PRM_axis,app.startLocation(1), app.startLocation(2), 'filled', 'r')
                                        scatter(app.Shortest_Path_PRM_axis,app.endLocation(1), app.endLocation(2), 'filled', 'g')
                                        title(app.Shortest_Path_PRM_axis,'Shortest Path')
                                        hold(app.Shortest_Path_PRM_axis,'off');
                                        waitfor(rateofit);

                                    end
                                end
                                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                repeat_matrix(repeats,1) = distance;
                                %                 disp(repeat_matrix);
                            end
                        end
                    end
                    if app.type_of_selection == 1
                        average_distance_for_node_num = mean(repeat_matrix(repeat_matrix>0));
                        waitfor(rateofit);
                        if average_distance_for_node_num > 0
                            distance_matrix(iterations,2)= average_distance_for_node_num;
                            waitfor(rateofit);
                        end
                    end
                    %                 if type_of_selection == 2
                    %                     min_distance_for_node_num = min(repeat_matrix(repeat_matrix>0));
                    %                 end
                    %                 %     disp(longest_distance_for_node_num);
                    waitfor(rateofit);

                    if non_zero_matrix(1,1) ~= 0
                        %working out the moving mean
                        distance_mean = movmean(distance_matrix(1:iterations,2),10);

                        cla(app.Average_distance_at_nodes_PRM_axis)
                        hold(app.Average_distance_at_nodes_PRM_axis,'on');
                        plot(app.Average_distance_at_nodes_PRM_axis,distance_matrix(1:iterations,1),distance_matrix(1:iterations,2),distance_matrix(1:iterations,1),distance_mean)
                        title(app.Average_distance_at_nodes_PRM_axis,'Path distance at each number of nodes')
                        xlabel(app.Average_distance_at_nodes_PRM_axis,'Number of nodes')
                        ylabel(app.Average_distance_at_nodes_PRM_axis,'Distance [m]')
                        hold(app.Average_distance_at_nodes_PRM_axis,'off');
                        % Wait to iterate at the proper rate
                        waitfor(rateofit);
                        if non_zero_matrix(1,1) > 0
                            no_zero_distance_mean = movmean(non_zero_matrix(1:non_zero_count,2),20);

                            cla(app.Average_distance_at_nodes_no_zero_PRM_axis)
                            hold(app.Average_distance_at_nodes_no_zero_PRM_axis,'on');
                            plot(app.Average_distance_at_nodes_no_zero_PRM_axis,non_zero_matrix(1:non_zero_count,1),non_zero_matrix(1:non_zero_count,2),non_zero_matrix(1:non_zero_count,1),no_zero_distance_mean)
                            title(app.Average_distance_at_nodes_no_zero_PRM_axis,'Path distance at each number of nodes (no zero)')
                            xlabel(app.Average_distance_at_nodes_no_zero_PRM_axis,'Number of nodes')
                            ylabel(app.Average_distance_at_nodes_no_zero_PRM_axis,'Distance [m]')
                            hold(app.Average_distance_at_nodes_no_zero_PRM_axis,'off');
                            % Wait to iterate at the proper rate
                            waitfor(rateofit);
                        end
                        waitfor(rateofit);
                    else
                        waitfor(rateofit);
                    end
                    % Wait to iterate at the proper rate
                    waitfor(rateofit);

                    %Path timing operations and plotting graph
                    Path_find_time(iterations)=toc;
                    Path_time_distance_mean = movmean(Path_find_time(1:iterations),10);
                    cla(app.Time_taken)
                    hold(app.Time_taken,'on');
                    plot(app.Time_taken,distance_matrix(1:iterations,1),Path_find_time,distance_matrix(1:iterations,1),Path_time_distance_mean);
                    title(app.Time_taken,'Time to find path')
                    xlabel(app.Time_taken,'Number of nodes')
                    ylabel(app.Time_taken,'Time [s]')
                    hold(app.Time_taken,'off');
                    total_time = sum(Path_find_time);
                    if total_time ~=0
                        app.Total_path_time_2.Value = total_time;
                    end
                end

                if non_zero_matrix(1,1) ~= 0
                    show(app.map,'Parent',app.Shortest_Path_PRM_axis);
                    hold(app.Shortest_Path_PRM_axis,'on');
                    plot(app.Shortest_Path_PRM_axis,app.shortest_path_PRM(:,1),app.shortest_path_PRM(:,2), 'o-');
                    title(app.Shortest_Path_PRM_axis,'Shortest Path')
                    hold(app.Shortest_Path_PRM_axis,'off');

                else
                    app.lowest_feasible_node_PRM = 0;
                    app.lowest_feasible_distance_PRM = 0;
                    app.first_feasible_path_PRM = 0;
                    app.lowest_distance_node_PRM = 0;
                    app.lowest__distance_value_PRM = 0;
                    app.Shortest_path_PRM = 0;
                end
                app.path = app.shortest_path_PRM;

                if app.lowest_feasible_node_PRM ~=0
                    app.lowest_feasible_node_PRM_field.Value = app.lowest_feasible_node_PRM;
                    app.lowest_feasible_distance_PRM_field.Value = app.lowest_feasible_distance_PRM;
                    app.lowest_distance_node_PRM_field.Value = app.lowest_distance_node_PRM;
                    app.lowest_distance_value_PRM_field.Value = app.lowest_distance_value_PRM;
                end

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.RRTRapidlyexploringRandomTreeCheckBox.Value == 1
                %Rapidly-Exploring Random Tree RRT

                % For loop used for establishing the robot motion and creating the map
                sampleTime = 0.001;            % Sample time [s]
                t = 0:sampleTime:100;         % Time array

                %set rate to iterate
                rateofit = rateControl(1/sampleTime);


                cla(app.Average_distance_at_nodes_RRT_axis)
                cla(app.Average_distance_at_nodes_no_zero_RRT_axis)

                app.start_MaxIterations_RRT = app.start_maxIterations.Value;
                app.finish_MaxIterations_RRT = app.finish_maxIterations.Value;
                app.increase_MaxIterations_RRT = app.increase_maxIterations.Value;

                app.MaxNumTreeNodes_RRT = app.MaxNumofTreeNodes.Value;
                app.max_connection_distance_RRT = app.connection_distance.Value;
                app.num_of_repeats_RRT = app.number_of_repeats.Value;

                % Set the start and goal states.
                startLocation = [app.startLocation(1,1),app.startLocation(1,2),0];
                endLocation = [app.endLocation(1,1),app.endLocation(1,2),0];

                % Create a state space.
                ss = stateSpaceSE2;

                % Create an occupanyMap-based state validator using the created state space.
                sv = validatorOccupancyMap(ss);

                sv.Map = app.map;

                % Set validation distance for the validator.
                sv.ValidationDistance = 1;

                % Update state space bounds to be the same as map limits.
                ss.StateBounds = [(app.map.XWorldLimits);(app.map.YWorldLimits); [-pi pi]];

                planner = plannerRRT(ss,sv);
                planner.MaxConnectionDistance = app.max_connection_distance_RRT;
                planner.MaxNumTreeNodes = app.MaxNumTreeNodes_RRT;
                planner.MaxIterations = app.start_MaxIterations_RRT;

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %Presetting values needed for calculations
                iteration_count = 0;

                app.first_feasible_path_RRT = 0;
                app.lowest_feasible_distance_RRT = 0;

                app.lowest_distance_value_RRT = 0;
                app.shortest_path_RRT = 0;
                Path_find_time=0;

                non_zero_count = 0;
                current_iteration = 0;
                %Calculating the amount of iterations required
                iterations = (app.finish_MaxIterations_RRT-app.start_MaxIterations_RRT)/app.increase_MaxIterations_RRT;
                repeats = app.num_of_repeats_RRT;
                distance_matrix = zeros(iterations,2);
                repeat_matrix = zeros(repeats,1);
                non_zero_matrix = zeros(iterations,2);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % main code to produce paths
                for iterations = 1:iterations
                    tic
                    % Increment and display the iteration count
                    iteration_count = iteration_count + 1;
                    disp(['Performing iteration ' num2str(iteration_count) '...'])
                    app.CurrentIterationEditField.Value = iterations;

                    % No feasible path found yet, increase the number of nodes
                    planner.MaxIterations = planner.MaxIterations + app.increase_MaxIterations_RRT;
                    disp(['Number of Node_iterations = ' num2str(planner.MaxIterations)])
                    app.NumberofnodesEditField.Value = planner.MaxIterations;

                    % Repeat for each num of nodes to increase chance of path
                    for repeats =1:repeats

                        % Plan a path with default settings.
                        % Randomise node placement
                        rng('shuffle')
                        % Find new path
                        [pthObj,solnInfo] = plan(planner,startLocation,endLocation);
                        app.path = [pthObj.States(:,1),pthObj.States(:,2)];

                        %Calculate the distance of the path
                        [distance] = Path_length_func(app.path);
                        %         disp(distance)

                        show(app.map, 'Parent', app.Start_end_points)
                        hold(app.Start_end_points,'on');

                        plot(app.Start_end_points,solnInfo.TreeData(:,1),solnInfo.TreeData(:,2),'.-'); % tree expansion
                        plot(app.Start_end_points,pthObj.States(:,1),pthObj.States(:,2),'r-','LineWidth',2); % draw path
                        title(app.Start_end_points,'Path Through Probabilistic Roadmap')
                        hold(app.Start_end_points,'off');

                        % Store node number and distance in a matrix
                        distance_matrix(iterations,1)= planner.MaxIterations;
                        distance_matrix(iterations,2)= distance;

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        % If there is no path then display no path
                        if distance == 0
                            %             disp('no path')

                            % If there is a path then...
                        else
                            if distance > 0
                                %                 disp(path)

                                %setting the count of the non zero matrix
                                if iterations ~= current_iteration
                                    current_iteration = iterations;
                                    non_zero_count = non_zero_count + 1;
                                end
                                % setting the lowest feasible path and node
                                if non_zero_count == 1
                                    app.lowest_feasible_node_RRT = planner.MaxIterations;
                                    app.lowest_feasible_distance_RRT = distance;
                                    app.first_feasible_path_RRT = app.path;
                                    disp(total_time)

                                    show(app.map,'Parent',app.First_Feasible_Path_RRT_axis);
                                    hold(app.First_Feasible_Path_RRT_axis,'on');
                                    plot(app.First_Feasible_Path_RRT_axis,app.first_feasible_path_RRT(:,1),app.first_feasible_path_RRT(:,2), 'o-');
                                    title(app.First_Feasible_Path_RRT_axis,'First Feasible Path')
                                    hold(app.First_Feasible_Path_RRT_axis,'off');

                                end
                                %If first repeat then set matrix value to value
                                if non_zero_matrix(non_zero_count,:) == 0
                                    non_zero_matrix(non_zero_count,:) = distance;
                                end

                                % Compare new matrix value to old value
                                if distance <= non_zero_matrix(non_zero_count,:)

                                    % If value is smaller replace value in matrix
                                    non_zero_matrix(non_zero_count,1) = planner.MaxIterations;
                                    non_zero_matrix(non_zero_count,2) = distance;

                                    % Set lowest distance value to the lowest in the matrix
                                    % so far
                                    app.lowest_distance_value_RRT = min(non_zero_matrix(1:non_zero_count,2));
                                    %Compare current value to lowest value in non zero matrix
                                    if distance == app.lowest_distance_value_RRT
                                        %Set shortest path to the current path
                                        app.shortest_path_RRT = app.path;
                                        app.lowest_distance_node_RRT = planner.MaxIterations;
                                        disp(total_time)

                                        show(app.map, 'Parent', app.Shortest_Path_RRT_axis)
                                        hold(app.Shortest_Path_RRT_axis,'on');
                                        plot(app.Shortest_Path_RRT_axis,solnInfo.TreeData(:,1),solnInfo.TreeData(:,2),'.-'); % tree expansion
                                        plot(app.Shortest_Path_RRT_axis,pthObj.States(:,1),pthObj.States(:,2),'r-','LineWidth',2); % draw path
                                        title(app.Shortest_Path_RRT_axis,'Shortest Path Through Probabilistic Roadmap')
                                        hold(app.Shortest_Path_RRT_axis,'off');
                                        waitfor(rateofit);

                                    end
                                end
                            end
                        end
                        repeat_matrix(repeats,1) = distance;
                    end

                    %     disp(repeat_matrix);
                    if app.type_of_selection == 1
                        average_distance_for_node_num = mean(repeat_matrix(repeat_matrix>0));

                        if average_distance_for_node_num > 0
                            distance_matrix(iterations,2)= average_distance_for_node_num;
                        end
                    end
                    %                 if type_of_selection == 2
                    %                     min_distance_for_node_num = min(repeat_matrix(repeat_matrix>0));
                    %                 end
                    %                 %     disp(longest_distance_for_node_num);

                    %working out the moving mean
                    distance_mean = movmean(distance_matrix(1:iterations,2),10);

                    cla(app.Average_distance_at_nodes_RRT_axis)
                    hold(app.Average_distance_at_nodes_RRT_axis,'on');
                    plot(app.Average_distance_at_nodes_RRT_axis,distance_matrix(1:iterations,1),distance_matrix(1:iterations,2),distance_matrix(1:iterations,1),distance_mean)
                    title(app.Average_distance_at_nodes_RRT_axis,'Path distance at each number of nodes')
                    xlabel(app.Average_distance_at_nodes_RRT_axis,'Number of branch iterations')
                    ylabel(app.Average_distance_at_nodes_RRT_axis,'Distance [m]')
                    hold(app.Average_distance_at_nodes_RRT_axis,'off');
                    % Wait to iterate at the proper rate
                    waitfor(rateofit);

                    if non_zero_matrix(1,1) > 0
                        no_zero_distance_mean = movmean(non_zero_matrix(1:non_zero_count,2),20);

                        cla(app.Average_distance_at_nodes_no_zero_RRT_axis)
                        hold(app.Average_distance_at_nodes_no_zero_RRT_axis,'on');
                        plot(app.Average_distance_at_nodes_no_zero_RRT_axis,non_zero_matrix(1:non_zero_count,1),non_zero_matrix(1:non_zero_count,2),non_zero_matrix(1:non_zero_count,1),no_zero_distance_mean)
                        title(app.Average_distance_at_nodes_no_zero_RRT_axis,'Path distance at each number of nodes (no zero)')
                        xlabel(app.Average_distance_at_nodes_no_zero_RRT_axis,'Number of branch iterations')
                        ylabel(app.Average_distance_at_nodes_no_zero_RRT_axis,'Distance [m]')
                        hold(app.Average_distance_at_nodes_no_zero_RRT_axis,'off');
                        % Wait to iterate at the proper rate
                        waitfor(rateofit);

                    else
                        no_zero_distance_mean = 0;
                    end

                    % Wait to iterate at the proper rate
                    waitfor(rateofit);

                    %Path timing operations and plotting graph
                    Path_find_time(iterations)=toc;
                    Path_time_distance_mean_RRT = movmean(Path_find_time(1:iterations),10);
                    cla(app.Time_taken)
                    hold(app.Time_taken,'on');
                    plot(app.Time_taken,distance_matrix(1:iterations,1),Path_find_time,distance_matrix(1:iterations,1),Path_time_distance_mean_RRT);
                    title(app.Time_taken,'Time to find path')
                    xlabel(app.Time_taken,'Number of branch iterations')
                    ylabel(app.Time_taken,'Time [s]')
                    hold(app.Time_taken,'off');
                    total_time = sum(Path_find_time);
                    if total_time ~=0
                        app.Total_path_time_2.Value = total_time;
                    end

                end

                % If there is no paths found set values to 0
                if non_zero_matrix(1,1) ~= 0

                else
                    app.lowest_feasible_node_RRT = 0;
                    app.lowest_feasible_distance_RRT = 0;
                    app.first_feasible_path_RRT = 0;
                    app.lowest_distance_node_RRT = 0;
                    app.lowest__distance_value_RRT = 0;
                    app.shortest_path_RRT = 0;
                end

                if app.lowest_feasible_node_RRT ~=0
                    app.lowest_feasible_node_RRT_field.Value = app.lowest_feasible_node_RRT;
                    app.lowest_feasible_distance_RRT_field.Value = app.lowest_feasible_distance_RRT;
                    app.lowest_distance_node_RRT_field.Value = app.lowest_distance_node_RRT;
                    app.lowest_distance_value_RRT_field.Value = app.lowest_distance_value_RRT;

                end
                app.path = app.shortest_path_RRT;
                disp(app.path)
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if app.GAGeneticAlgorithmCheckBox.Value == 1

                map = app.map;
                mapInflated = copy(map);
                inflate(mapInflated,0.2);

                % Initalising variables
                num_dot = app.Number_of_dots.Value;
                movements = app.Max_number_of_movements.Value;
                generations = app.Generations.Value;

                avg_distance = 0;
                avg_distance_top_dots = 0;
                distance_top_dot = 0;
                dot_movement = {1};
                all_movements_matrix = {1};
                dot_selection_to_progress = app.Number_of_dots_to_progress.Value;
                mutation_size = num_dot - dot_selection_to_progress;
                reached_end = 0;
                all_dot_coords = {1};
                path_distance_matrix = 0;
                dots_to_reach_end_progress = 0;
                avg_shortest_distance_top_dots = 0;
                shortest_distance_top_dot=0;
                shortest_distance_dots_matrix = NaN;

                %loop repeating for each generation
                for generations = 1 : generations
                    tic
                    generations_matrix = (1:generations)';
                    disp(generations)
                    dots_to_reach_end = 0;
                    for num_dot = 1:num_dot
                        %generating inital random movement matrices
                        if generations == 1
                            x_y_matrix = [randi([-1,1],movements,1),randi([-1,1],movements,1)];
                            dot_movement{1,num_dot} = x_y_matrix;
                        else
                            %setting new generation to best of last gen
                            dot_movement{1,num_dot} = next_gen{1,num_dot};
                        end
                        %recording all movements in matrix
                        all_movements_matrix{1,generations} = dot_movement;
                    end


                    % dot_movement{1,1}(1,1)
                    % dot_movement{1,1}(1,2)
                    initial_x = app.startLocation(1);
                    initial_y = app.startLocation(2);
                    x_total = initial_x.* ones(num_dot,1);
                    y_total = initial_y.* ones(num_dot,1);


                    dot_matrix = ones(num_dot,1);
                    final_coords = ones(num_dot,2);
                    for movements = 1:movements
                        for num_dot = 1:num_dot

                            if dot_matrix(num_dot) == 1

                                dot_change_x = dot_movement{1,num_dot}(movements,1);
                                x_total(num_dot) = x_total(num_dot) + dot_change_x;

                                dot_change_y = dot_movement{1,num_dot}(movements,2);
                                y_total(num_dot) = y_total(num_dot) + dot_change_y;

                                all_dot_coords{num_dot,generations}(movements,1) = (x_total(num_dot));
                                all_dot_coords{num_dot,generations}(movements,2) = (y_total(num_dot));

                                if checkOccupancy(map,[x_total(num_dot) y_total(num_dot)]) == 1
                                    dot_matrix(num_dot) = 0;
                                else
                                    if x_total(num_dot) == app.endLocation(1) && y_total(num_dot) == app.endLocation(2)
                                        reached_end = 1;
                                        dot_matrix(num_dot) = 0;
                                        %                         disp('reached_goal')
                                        %                         disp(x_total(num_dot))
                                        %                         disp(y_total(num_dot))
                                        reaching_end_dot_movement = all_dot_coords(num_dot,generations);
                                    end
                                end
                            else
                                %                 final_coords(num_dot,:) = [x_total(num_dot), y_total(num_dot)];
                            end
                            final_coords(num_dot,:) = [x_total(num_dot), y_total(num_dot)];
                        end
                        %                         figure(1)
                        %         refreshdata
                        drawnow

                        show(map, 'Parent', app.Start_end_points)
                        hold(app.Start_end_points,'on');
                        plot(app.Start_end_points,x_total((1)),y_total((1)),'*');
                        plot(app.Start_end_points,x_total((2:dot_selection_to_progress)),y_total((2:dot_selection_to_progress)),'x');
                        plot(app.Start_end_points,x_total((dot_selection_to_progress:num_dot)),y_total((dot_selection_to_progress:num_dot)),'.');
                        scatter(app.Start_end_points,app.startLocation(1), app.startLocation(2), 'filled', 'r')
                        scatter(app.Start_end_points,app.endLocation(1), app.endLocation(2), 'filled', 'g')
                        title(app.Start_end_points,'Shortest Path Through Probabilistic Roadmap')
                        hold(app.Start_end_points,'off');
                    end

                    %calculating distance to end point
                    distance_to_end = ones(num_dot,1);
                    for num_dot = 1:num_dot
                        distance_to_end(num_dot) = sqrt(((app.endLocation(1) - final_coords(num_dot,1))^2)+((app.endLocation(2) - final_coords(num_dot,2))^2));
                        if reached_end == 1
                            disp(total_time)
                            if distance_to_end(num_dot) == 0
                                dot_path = all_dot_coords{num_dot,generations};
                                [path_distance]= Path_length_func(dot_path);
                                path_distance_matrix(num_dot,generations) = path_distance;
                                dots_to_reach_end = dots_to_reach_end + 1;
                                if dots_to_reach_end_progress < dot_selection_to_progress
                                    dots_to_reach_end_progress = dots_to_reach_end_progress + 1;
                                end
                            end
                        end
                    end
                    avg_distance(generations) = mean(distance_to_end);

                    %working out number of mutant groups and size
                    number_mutants_groups = (num_dot - dot_selection_to_progress)/dot_selection_to_progress;
                    number_in_mutant_groups = (num_dot - dot_selection_to_progress) /number_mutants_groups;

                    path_distance_matrix_no_zero = path_distance_matrix; path_distance_matrix_no_zero(path_distance_matrix_no_zero==0) = [NaN];

                    %sorting dots by shortest path
                    if reached_end == 1
                        [shortest_distance_dots_matrix,Locations] = mink(path_distance_matrix_no_zero,dot_selection_to_progress);
                    else
                        shortest_distance_dots_matrix(generations)=NaN;
                    end
                    avg_shortest_distance_top_dots(generations) = mean(shortest_distance_dots_matrix(:,generations));
                    shortest_distance_top_dot(generations) = shortest_distance_dots_matrix(1,generations);
                    %     disp(shortest_distance_dots_matrix)

                    %sorting dots by min distance to end
                    [top_dots,I] = mink(distance_to_end,dot_selection_to_progress);
                    avg_distance_top_dots(generations) = mean(top_dots);
                    distance_top_dot(generations) = top_dots(1);

                    %choosing the best dots
                    best_dots = {1};

                    if reached_end == 1
                        for dots_to_reach_end_progress = 1:dots_to_reach_end_progress
                            best_dots{dots_to_reach_end_progress} = (dot_movement(Locations(dots_to_reach_end_progress,generations)));
                        end
                        none_end_dots = dot_selection_to_progress - dots_to_reach_end_progress;
                        if none_end_dots > 0
                            for dot_selection_to_progress = dots_to_reach_end:dot_selection_to_progress
                                best_dots{dot_selection_to_progress} = (dot_movement(I(dot_selection_to_progress)));
                            end
                        end
                    else
                        for dot_selection_to_progress = 1:dot_selection_to_progress
                            best_dots{dot_selection_to_progress} = (dot_movement(I(dot_selection_to_progress)));
                        end
                    end


                    %initalising next gen variables and matrices
                    baby_dots = ones(movements,2);
                    next_gen={1};

                    for number_mutants_groups = 1:number_mutants_groups
                        for dot_selection_to_progress=1:dot_selection_to_progress

                            next_gen{1,dot_selection_to_progress} = best_dots{1,dot_selection_to_progress}{1,1};
                            for movements = 1:movements
                                baby_dots(movements,:) = [best_dots{1,dot_selection_to_progress}{1,1}(movements,1)*randsrc(1,1,[1,0,-1;0.8,0.1,0.1]), best_dots{1,dot_selection_to_progress}{1,1}(movements,2)*randsrc(1,1,[1,0,-1;0.8,0.1,0.1]);];

                            end
                            place = number_mutants_groups*number_in_mutant_groups +(dot_selection_to_progress);
                            next_gen{1,place} = baby_dots;
                        end
                    end

                    cla(app.avg_distance)
                    hold(app.avg_distance,'on');
                    plot(app.avg_distance,generations_matrix,avg_distance)
                    title(app.avg_distance,'Average distance at each number of nodes (no zero)')
                    hold(app.avg_distance,'off');

                    cla(app.avg_distance_top_dots)
                    hold(app.avg_distance_top_dots,'on');
                    plot(app.avg_distance_top_dots,generations_matrix,avg_distance_top_dots)
                    title(app.avg_distance_top_dots,'Average distance at each number of nodes (no zero)')
                    hold(app.avg_distance_top_dots,'off');

                    cla(app.distance_top_dot)
                    hold(app.distance_top_dot,'on');
                    plot(app.distance_top_dot,generations_matrix,distance_top_dot)
                    title(app.distance_top_dot,'Average distance at each number of nodes (no zero)')
                    hold(app.distance_top_dot,'off');

                    if reached_end == 1

                        show(app.map, 'Parent', app.Shortest_path)
                        hold(app.Shortest_path,'on');
                        plot(app.Shortest_path,all_dot_coords{Locations(1,generations),generations}(:,1),all_dot_coords{Locations(1,generations),generations}(:,2),'-o')
                        scatter(app.Shortest_path,app.startLocation(1), app.startLocation(2), 'filled', 'r')
                        scatter(app.Shortest_path,app.endLocation(1), app.endLocation(2), 'filled', 'g')
                        title(app.Shortest_path,'Shortest Path Through Probabilistic Roadmap')
                        hold(app.Shortest_path,'off');

                        cla(app.avg_shortest_distance_top_dots)
                        hold(app.avg_shortest_distance_top_dots,'on');
                        plot(app.avg_shortest_distance_top_dots,generations_matrix,avg_shortest_distance_top_dots)
                        title(app.avg_shortest_distance_top_dots,'Average distance at each number of nodes (no zero)')
                        hold(app.avg_shortest_distance_top_dots,'off');

                        cla(app.shortest_distance_top_dot)
                        hold(app.shortest_distance_top_dot,'on');
                        plot(app.shortest_distance_top_dot,generations_matrix,shortest_distance_top_dot)
                        title(app.shortest_distance_top_dot,'Average distance at each number of nodes (no zero)')
                        hold(app.shortest_distance_top_dot,'off');


                    end

                    %Path timing operations and plotting graph
                    Path_find_time(generations)=toc;
                    Path_time_distance_mean_RRT = movmean(Path_find_time(1:generations),10);
                    cla(app.Time_taken)
                    hold(app.Time_taken,'on');
                    plot(app.Time_taken,generations_matrix(1:generations,1),Path_find_time,generations_matrix(1:generations,1),Path_time_distance_mean_RRT);
                    title(app.Time_taken,'Time to find path')
                    hold(app.Time_taken,'off');
                    total_time = sum(Path_find_time);
                    if total_time ~=0
                        app.Total_path_time_2.Value = total_time;
                    end
                end
                app.shortest_Path_genetic = all_dot_coords{Locations(1,generations),generations}(:,1),all_dot_coords{Locations(1,generations),generations}(:,2)
                app.path = app.shortest_Path_genetic
            end
            disp(app.path)
        end

        % Button pushed function: ShowpointsButton
        function ShowpointsButtonPushed(app, event)
            %showing the start and end points on the main map that have been inputted into
            %the respective numerical input boxes

            app.startLocation = [app.Start_location_x.Value app.Start_location_y.Value];
            app.endLocation = [app.End_location_x.Value app.End_location_y.Value];
            show(app.map,'Parent',app.Start_end_points);
            hold(app.Start_end_points,'on');
            scatter(app.Start_end_points,app.startLocation(1), app.startLocation(2), 'filled', 'r')
            scatter(app.Start_end_points,app.endLocation(1), app.endLocation(2), 'filled', 'g')
            title(app.Start_end_points,'Shortest Path')
            hold(app.Start_end_points,'off');

        end

        % Value changed function: SelectMapDropDown
        function SelectMapDropDownValueChanged(app, event)
            % Map selection

            value = app.SelectMapDropDown.Value;

            %When the user selects a map from the drop down menu then a
            %binary occupancy map is generated and the map is inflated if
            %needed and stored under the global variable app.map
            switch value
                case 'Select Map'

                    %If no map selected the map figures are cleared
                    cla(app.Start_end_points)
                    cla(app.First_Feasible_Path_RRT_axis)
                    cla(app.Shortest_Path_RRT_axis)
                    cla(app.Map1)
                    cla(app.Map2)

                case 'Simple Map' % User Simple Map.
                    load exampleMaps.mat
                    app.occupancyMatrix = simpleMap;
                    simpleMap = binaryOccupancyMap(simpleMap,1);
                    app.non_inflate_map = simpleMap;
                    inflate(simpleMap, 0.2);
                    app.map = simpleMap;

                case 'Complex Map' % User Complex Map.
                    load exampleMaps.mat
                    app.occupancyMatrix = complexMap;
                    app.map = binaryOccupancyMap(complexMap,1);


                case 'Task Map' % Map specified by the task to be created
                    app.occupancyMatrix = readmatrix('ACS_Map.csv');
                    app.map = binaryOccupancyMap(app.occupancyMatrix);

                case 'Large Map' % User made Map.
                    image = imread('20 by 20 orthogonal maze.png');
                    grayimage = rgb2gray(image);
                    bwimage = grayimage < 0.5;
                    app.occupancyMatrix = bwimage;
                    created_map = binaryOccupancyMap(bwimage);
                    app.map = created_map;

            end

            %Display the selected map on each of the figures on the GUI
            show(app.map, 'Parent', app.Start_end_points)
            show(app.map, 'Parent', app.Map1)
            show(app.map, 'Parent', app.First_Feasible_Path_PRM_axis)
            show(app.map, 'Parent', app.Shortest_Path_PRM_axis)
            show(app.map, 'Parent', app.First_Feasible_Path_RRT_axis)
            show(app.map, 'Parent', app.Shortest_Path_RRT_axis)
            show(app.map, 'Parent', app.Map2)
            show(app.map, 'Parent', app.All_path_comparison2)

            title(app.Map2,'Robot Map')

            %Calculate the size of the map and create new map as an empty
            %map to be shown in the sensors figure
            [mapdimy,mapdimx] = size(app.occupancyMatrix);
            app.emptyMap = binaryOccupancyMap(mapdimx,mapdimy,10); %Recreates an empty map using the size details of the currently selected map
            show(app.emptyMap, 'Parent', app.Map3)

        end

        % Button pushed function: Plot_robot_path
        function Plot_robot_pathPushed(app, event)
            % Setup sensors
            sensor = rangeSensor;
            sensor.Range = [0,100];

            % Clears the axes used without resetting them
            cla(app.Map1);
            cla(app.Map2);
            cla(app.Map3);

            sampleTime = 0.1;            % Sample time [s]
            t = 0:sampleTime:100;         % Time array
            poses = zeros(3,numel(t));    % Pose matrix

            initPose = [app.path(1,1) app.path(1,2), pi/2];
            poses(:,1) = initPose';

            app.controller.Waypoints = app.path; %Define the controller waypoints
            goal = [app.path(end,1) app.path(end,2)]';

            %set rate to iterate
            rateofit = rateControl(1/sampleTime);

            % Loads axes with required map figures and path
            show(app.map, 'Parent', app.Map1)
            hold(app.Map1,'on');
            plot(app.path(:,1),app.path(:,2), 'o-', 'Parent', app.Map1);
            title(app.Map1,'Choosen Path')
            hold(app.Map1,'off');

            show(app.map, 'Parent', app.Map2)
            title(app.Map2,'Robot Map')

            show(app.emptyMap, 'Parent', app.Map3)
            title(app.Map3,'Sensor Map')

            % For loop used for establishing the robot motion and creating the map
            for idx = 1:numel(t)
                position=poses(:,idx)';
                currPose=position(1:2);

                % End path following if vehicle has reached goal position within tolerance of 1m
                dist=norm(goal'-currPose); % Used to get the tolerance value of the current position of the robot
                if (dist<1)
                    disp("Goal position reached")
                    break;
                end

                % Update map by taking sensor measurements
                %                 cla(app.Map3);
                [ranges, angles] = sensor(position,app.map);
                scan = lidarScan(ranges,angles);
                validScan = removeInvalidData(scan,'RangeLimits',[0,sensor.Range(2)]);
                insertRay(app.emptyMap,position,validScan,sensor.Range(2));
                show(app.emptyMap, 'Parent', app.Map3);

                % Run the Pure Pursuit controller and convert output to wheel speeds
                [vRef,wRef] = app.controller(poses(:,idx));

                % Perform forward discrete integration step
                vel = derivative(app.currentRobot, poses(:,idx), [vRef wRef]);
                poses(:,idx+1) = poses(:,idx) + vel*sampleTime;

                % Update visualization
                plotTrvec = [poses(1:2, idx+1); 0];
                plotRot = axang2quat([0 0 1 poses(3, idx+1)]);

                % Plot robot onto known map

                % Delete image of the last robot to prevent displaying multiple robots
                if idx > 1
                    show(app.map, 'Parent', app.Map2)
                    hold(app.Map2,'on');
                    scatter(app.Map2,app.startLocation(1), app.startLocation(2), 'filled', 'r')
                    scatter(app.Map2,app.endLocation(1), app.endLocation(2), 'filled', 'g')
                    hold(app.Map2,'off');
                end
                hold on
                plotTransforms(plotTrvec', plotRot, 'MeshFilePath', 'groundvehicle.stl', 'View', '2D', 'FrameSize', 1, 'Parent', app.Map3);

                % Plot robot on new map
                plotTransforms(plotTrvec', plotRot, 'MeshFilePath', 'groundvehicle.stl', 'View', '2D', 'FrameSize', 1, 'Parent', app.Map2);

                % Wait to iterate at the proper rate
                waitfor(rateofit);
            end

        end

        % Value changed function: Select_robot_DropDown
        function Select_robot_DropDownValueChanged(app, event)
            value = app.Select_robot_DropDown.Value;

            switch value
                case 'Select Robot'
                    %                     app.currentRobot = 0

                case 'unicycle'
                    unicycle = unicycleKinematics("VehicleInputs","VehicleSpeedHeadingRate");
                    app.controller = controllerPurePursuit('DesiredLinearVelocity',3,'MaxAngularVelocity',2*pi);
                    app.currentRobot=unicycle;

                case 'bicycle'
                    bicycle = bicycleKinematics("VehicleInputs","VehicleSpeedHeadingRate");
                    bicycle.MaxSteeringAngle = pi/8;
                    app.controller = controllerPurePursuit('DesiredLinearVelocity',3,'MaxAngularVelocity',2*pi);
                    app.currentRobot=bicycle;

                case 'diffDrive'
                    diffDrive = differentialDriveKinematics("VehicleInputs","VehicleSpeedHeadingRate");
                    diffDrive.WheelSpeedRange = [-50 50]*2*pi;
                    app.controller = controllerPurePursuit("Waypoints",app.path,'DesiredLinearVelocity',3,'MaxAngularVelocity',2*pi);
                    app.currentRobot = diffDrive;

            end
        end

        % Value changed function: SelectpathDropDown
        function SelectpathDropDownValueChanged(app, event)

        end

        % Button pushed function: CalculateRobotspathsButton
        function CalculateRobotspathsButtonPushed(app, event)
            sampleTime = 0.01;            % Sample time [s]
            tVec = 0:sampleTime:100;         % Time array
            %set rate to iterate
            rateofit = rateControl(1/sampleTime);
            
            initPose = [app.path(1,1) app.path(1,2), pi/2];
            poses(:,1) = initPose;

            goalPoints = [app.path(end,:), 0];
            goalRadius = 1;
disp(goalPoints)
            
            
            % Define a controller. Each robot requires its own controller
            controller1 = controllerPurePursuit("Waypoints",app.path,"DesiredLinearVelocity",3,"MaxAngularVelocity",2*pi);
            controller2 = controllerPurePursuit("Waypoints",app.path,"DesiredLinearVelocity",3,"MaxAngularVelocity",2*pi);
            controller3 = controllerPurePursuit("Waypoints",app.path,"DesiredLinearVelocity",3,"MaxAngularVelocity",2*pi);

            unicycle = unicycleKinematics("VehicleInputs","VehicleSpeedHeadingRate");
            bicycle = bicycleKinematics("VehicleInputs","VehicleSpeedHeadingRate","MaxSteeringAngle",pi/8);
            diffDrive = differentialDriveKinematics("VehicleInputs","VehicleSpeedHeadingRate");
            diffDrive.WheelSpeedRange = [-50 50]*2*pi;


            show(app.map,'Parent',app.All_path_comparison2);
            hold(app.All_path_comparison2,'on');
            plot(app.All_path_comparison2,app.path(:,1),app.path(:,2),"kx-","MarkerSize",1);
            title(app.All_path_comparison2,'All robot paths')

            %             Compute trajectories for each kinematic model under motion control
            %             The results of the ODE solver can be easily viewed on a single plot using plotTransforms to visualize the results of all trajectories at once.
            %             The pose outputs must first be converted to indexed matrices of translations and quaternions.
            if app.UnicycleredCheckBox.Value == 1
                [tUnicycle,unicyclePose] = ode45(@(t,y)derivative(unicycle,y,exampleHelperMobileRobotController(controller1,y,goalPoints,goalRadius)),tVec,initPose);
                unicycleTranslations = [unicyclePose(:,1:2) zeros(length(unicyclePose),1)];
                unicycleRot = axang2quat([repmat([0 0 1],length(unicyclePose),1) unicyclePose(:,3)]);
                plotTransforms(unicycleTranslations(1:end,:), unicycleRot(1:end,:), 'MeshFilePath', 'groundvehicle.stl', 'View', '2D',"MeshColor","r", 'FrameSize', 0.1, 'Parent', app.All_path_comparison2);
                % plotTransforms(unicycleTranslations(1:10:end,:), unicycleRot(1:10:end,:), 'MeshFilePath', 'groundvehicle.stl', 'View', '2D',"MeshColor","r", 'FrameSize', 1, 'Parent', app.All_path_comparison2);
                disp('Unicycle completed')
                waitfor(rateofit);

            end

            if app.BicycleblueCheckBox.Value == 1
                [tBicycle,bicyclePose] = ode45(@(t,y)derivative(bicycle,y,exampleHelperMobileRobotController(controller2,y,goalPoints,goalRadius)),tVec,initPose);
                bicycleTranslations = [bicyclePose(:,1:2) zeros(length(bicyclePose),1)];
                bicycleRot = axang2quat([repmat([0 0 1],length(bicyclePose),1) bicyclePose(:,3)]);
                plotTransforms(bicycleTranslations(1:end,:),bicycleRot(1:end,:), 'MeshFilePath', 'groundvehicle.stl', 'View', '2D',"MeshColor","b", 'FrameSize', 0.1, 'Parent', app.All_path_comparison2);
                % plotTransforms(bicycleTranslations(1:10:end,:),bicycleRot(1:10:end,:), 'MeshFilePath', 'groundvehicle.stl', 'View', '2D',"MeshColor","b", 'FrameSize', 1, 'Parent', app.All_path_comparison2);
                disp('Bicycle completed')
                waitfor(rateofit);
            end

            if app.DifferentialdrivegreenCheckBox.Value == 1
                [tDiffDrive,diffDrivePose] = ode45(@(t,y)derivative(diffDrive,y,exampleHelperMobileRobotController(controller3,y,goalPoints,goalRadius)),tVec,initPose);
                diffDriveTranslations = [diffDrivePose(:,1:2) zeros(length(diffDrivePose),1)];
                diffDriveRot = axang2quat([repmat([0 0 1],length(diffDrivePose),1) diffDrivePose(:,3)]);
                plotTransforms(diffDriveTranslations(1:end,:),diffDriveRot(1:end,:), 'MeshFilePath', 'groundvehicle.stl', 'View', '2D',"MeshColor","g", 'FrameSize', 0.1, 'Parent', app.All_path_comparison2);
                % plotTransforms(diffDriveTranslations(1:10:end,:),diffDriveRot(1:10:end,:), 'MeshFilePath', 'groundvehicle.stl', 'View', '2D',"MeshColor","g", 'FrameSize', 1, 'Parent', app.All_path_comparison2);
                disp('Differentialdrive completed')
                waitfor(rateofit);
            end

            hold(app.All_path_comparison2,'off');

            disp('finished')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.PathSettingsTab = uitab(app.TabGroup3);
            app.PathSettingsTab.Title = 'Path Settings';
            app.Start_end_points = uiaxes(app.PathSettingsTab);
            title(app.Start_end_points, 'Title')
            xlabel(app.Start_end_points, 'X')
            ylabel(app.Start_end_points, 'Y')
            zlabel(app.Start_end_points, 'Z')
            app.Time_taken = uiaxes(app.PathSettingsTab);
            title(app.Time_taken, 'Title')
            xlabel(app.Time_taken, 'X')
            ylabel(app.Time_taken, 'Y')
            zlabel(app.Time_taken, 'Z')
            app.SelectMapDropDownLabel = uilabel(app.PathSettingsTab);
            app.SelectMapDropDownLabel.Text = 'Select Map';
            app.SelectMapDropDown = uidropdown(app.PathSettingsTab);
            app.SelectMapDropDown.Items = {'Select Map', 'Simple Map', 'Complex Map', 'Task Map', 'Large Map'};
            app.SelectMapDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMapDropDownValueChanged, true);
            app.SelectMapDropDown.Placeholder = 'Select Map';
            app.SelectMapDropDown.Value = 'Select Map';
            app.PlanPathButton = uibutton(app.PathSettingsTab, 'push');
            app.PlanPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlanPathButtonPushed, true);
            app.PlanPathButton.Text = 'Plan Path';
            app.Start_location_y = uieditfield(app.PathSettingsTab, 'numeric');
            app.Start_location_y.Value = 5;
            app.End_location_y = uieditfield(app.PathSettingsTab, 'numeric');
            app.End_location_y.Value = 5;
            app.ShowpointsButton = uibutton(app.PathSettingsTab, 'push');
            app.ShowpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowpointsButtonPushed, true);
            app.ShowpointsButton.Text = 'Show points';
            app.TabGroup = uitabgroup(app.PathSettingsTab);
            app.PRMTab = uitab(app.TabGroup);
            app.PRMTab.Title = 'PRM';
            app.initalnumberofnodesLabel = uilabel(app.PRMTab);
            app.initalnumberofnodesLabel.Text = 'inital number of nodes';
            app.inital_num_nodess_PRM = uieditfield(app.PRMTab, 'numeric');
            app.inital_num_nodess_PRM.Value = 10;
            app.finalnumberofnodesLabel = uilabel(app.PRMTab);
            app.finalnumberofnodesLabel.Text = 'final number of nodes';
            app.final_num_nodess_PRM = uieditfield(app.PRMTab, 'numeric');
            app.final_num_nodess_PRM.Value = 210;
            app.nodeincreaseLabel = uilabel(app.PRMTab);
            app.nodeincreaseLabel.Text = 'node increase';
            app.node_increasee_PRM = uieditfield(app.PRMTab, 'numeric');
            app.node_increasee_PRM.Value = 10;
            app.connectiondistanceLabel_2 = uilabel(app.PRMTab);
            app.connectiondistanceLabel_2.Text = 'connection distance';
            app.connection_Distance_PRM = uieditfield(app.PRMTab, 'numeric');
            app.connection_Distance_PRM.Value = 10;
            app.numberofrepeatsLabel = uilabel(app.PRMTab);
            app.numberofrepeatsLabel.Text = 'number of repeats';
            app.num_of_repeat_PRM = uieditfield(app.PRMTab, 'numeric');
            app.num_of_repeat_PRM.Value = 5;
            app.RRTTab = uitab(app.TabGroup);
            app.RRTTab.Title = 'RRT';
            app.startmaxiterationsLabel = uilabel(app.RRTTab);
            app.startmaxiterationsLabel.Text = 'start max iterations';
            app.start_maxIterations = uieditfield(app.RRTTab, 'numeric');
            app.start_maxIterations.Value = 400;
            app.maxiterationsincreaseLabel = uilabel(app.RRTTab);
            app.maxiterationsincreaseLabel.Text = 'max iterations increase ';
            app.increase_maxIterations = uieditfield(app.RRTTab, 'numeric');
            app.increase_maxIterations.Value = 10;
            app.connectiondistanceLabel = uilabel(app.RRTTab);
            app.connectiondistanceLabel.Text = 'connection distance';
            app.connection_distance = uieditfield(app.RRTTab, 'numeric');
            app.connection_distance.Value = 15;
            app.finishmaxiterationsLabel = uilabel(app.RRTTab);
            app.finishmaxiterationsLabel.Text = 'finish max iterations';
            app.finish_maxIterations = uieditfield(app.RRTTab, 'numeric');
            app.finish_maxIterations.Value = 600;
            app.numberofrepeatsLabel_2 = uilabel(app.RRTTab);
            app.numberofrepeatsLabel_2.Text = 'number of repeats';
            app.number_of_repeats = uieditfield(app.RRTTab, 'numeric');
            app.number_of_repeats.Value = 5;
            app.maxnumberoftreenodesLabel = uilabel(app.RRTTab);
            app.maxnumberoftreenodesLabel.Text = 'max number of  tree nodes';
            app.MaxNumofTreeNodes = uieditfield(app.RRTTab, 'numeric');
            app.MaxNumofTreeNodes.Value = 1000;
            app.GATab_2 = uitab(app.TabGroup);
            app.GATab_2.Title = 'GA';
            app.NumberofdotsLabel = uilabel(app.GATab_2);
            app.NumberofdotsLabel.Text = 'Number of dots';
            app.Number_of_dots = uieditfield(app.GATab_2, 'numeric');
            app.Number_of_dots.Value = 2000;
            app.GenerationsLabel = uilabel(app.GATab_2);
            app.GenerationsLabel.Text = 'Generations';
            app.Generations = uieditfield(app.GATab_2, 'numeric');
            app.Generations.Value = 20;
            app.ChanceofmutationLabel_2 = uilabel(app.GATab_2);
            app.ChanceofmutationLabel_2.Text = 'Chance of mutation';
            app.Chance_of_mutation = uieditfield(app.GATab_2, 'numeric');
            app.Chance_of_mutation.Value = 0.2;
            app.MaxnumberofmovementsLabel = uilabel(app.GATab_2);
            app.MaxnumberofmovementsLabel.Text = 'Max number of movements';
            app.Max_number_of_movements = uieditfield(app.GATab_2, 'numeric');
            app.Max_number_of_movements.Value = 150;
            app.NumberofdotstoprogressLabel = uilabel(app.GATab_2);
            app.NumberofdotstoprogressLabel.Text = 'Number of dots to progress';
            app.Number_of_dots_to_progress = uieditfield(app.GATab_2, 'numeric');
            app.Number_of_dots_to_progress.Value = 100;
            app.StartlocationLabel_2 = uilabel(app.PathSettingsTab);
            app.StartlocationLabel_2.Text = 'Start location';
            app.Start_location_x = uieditfield(app.PathSettingsTab, 'numeric');
            app.Start_location_x.Value = 5;
            app.EndlocationLabel_2 = uilabel(app.PathSettingsTab);
            app.EndlocationLabel_2.Text = 'End location';
            app.End_location_x = uieditfield(app.PathSettingsTab, 'numeric');
            app.End_location_x.Value = 95;
            app.GAGeneticAlgorithmCheckBox = uicheckbox(app.PathSettingsTab);
            app.GAGeneticAlgorithmCheckBox.Text = 'GA (Genetic Algorithm)';
            app.PRMProbabilisticRoadMapCheckBox = uicheckbox(app.PathSettingsTab);
            app.PRMProbabilisticRoadMapCheckBox.Text = 'PRM (ProbabilisticRoadMap)';
            app.RRTRapidlyexploringRandomTreeCheckBox = uicheckbox(app.PathSettingsTab);
            app.RRTRapidlyexploringRandomTreeCheckBox.Text = 'RRT (Rapidly-exploring Random Tree)';
            app.PathStatisticsPanel = uipanel(app.PathSettingsTab);
            app.PathStatisticsPanel.Title = 'Path Statistics';
            app.CurrentIterationEditFieldLabel = uilabel(app.PathStatisticsPanel);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.PathStatisticsPanel, 'numeric');
            app.TotalpathfindtimeLabel_2 = uilabel(app.PathStatisticsPanel);
            app.TotalpathfindtimeLabel_2.Text = 'Total path find time';
            app.Total_path_time_2 = uieditfield(app.PathStatisticsPanel, 'numeric');
            app.NumberofnodesEditFieldLabel = uilabel(app.PathStatisticsPanel);
            app.NumberofnodesEditFieldLabel.Text = 'Number of nodes';
            app.NumberofnodesEditField = uieditfield(app.PathStatisticsPanel, 'numeric');
            app.TimetocalculateeachiterationbluelineLabel = uilabel(app.PathSettingsTab);
            app.TimetocalculateeachiterationbluelineLabel.Text = 'Time to calculate each iteration(blue line)';
            app.RolliingaverageredlineLabel = uilabel(app.PathSettingsTab);
            app.RolliingaverageredlineLabel.Text = 'Rolliing average (red line)';
            app.SelectpathfindingmethodLabel = uilabel(app.PathSettingsTab);
            app.SelectpathfindingmethodLabel.Text = 'Select path finding method:';
            app.PathfindingoptionsLabel = uilabel(app.PathSettingsTab);
            app.PathfindingoptionsLabel.Text = 'Path finding options';
            app.ChoosecoordinatesLabel = uilabel(app.PathSettingsTab);
            app.ChoosecoordinatesLabel.Text = 'Choose coordinates';
            app.PathPerformanceTab = uitab(app.TabGroup3);
            app.PathPerformanceTab.Title = 'Path Performance';
            app.TabGroup2 = uitabgroup(app.PathPerformanceTab);
            app.PRMTab_2 = uitab(app.TabGroup2);
            app.PRMTab_2.Title = 'PRM';
            app.First_Feasible_Path_PRM_axis = uiaxes(app.PRMTab_2);
            title(app.First_Feasible_Path_PRM_axis, 'Title')
            xlabel(app.First_Feasible_Path_PRM_axis, 'X')
            ylabel(app.First_Feasible_Path_PRM_axis, 'Y')
            zlabel(app.First_Feasible_Path_PRM_axis, 'Z')
            app.Shortest_Path_PRM_axis = uiaxes(app.PRMTab_2);
            title(app.Shortest_Path_PRM_axis, 'Title')
            xlabel(app.Shortest_Path_PRM_axis, 'X')
            ylabel(app.Shortest_Path_PRM_axis, 'Y')
            zlabel(app.Shortest_Path_PRM_axis, 'Z')
            app.Average_distance_at_nodes_PRM_axis = uiaxes(app.PRMTab_2);
            title(app.Average_distance_at_nodes_PRM_axis, 'Title')
            xlabel(app.Average_distance_at_nodes_PRM_axis, 'X')
            ylabel(app.Average_distance_at_nodes_PRM_axis, 'Y')
            zlabel(app.Average_distance_at_nodes_PRM_axis, 'Z')
            app.Average_distance_at_nodes_no_zero_PRM_axis = uiaxes(app.PRMTab_2);
            title(app.Average_distance_at_nodes_no_zero_PRM_axis, 'Title')
            xlabel(app.Average_distance_at_nodes_no_zero_PRM_axis, 'X')
            ylabel(app.Average_distance_at_nodes_no_zero_PRM_axis, 'Y')
            zlabel(app.Average_distance_at_nodes_no_zero_PRM_axis, 'Z')
            app.lowest_feasible_nodeLabel = uilabel(app.PRMTab_2);
            app.lowest_feasible_nodeLabel.Text = 'lowest_feasible_node';
            app.lowest_feasible_node_PRM_field = uieditfield(app.PRMTab_2, 'numeric');
            app.lowest_feasible_distanceLabel = uilabel(app.PRMTab_2);
            app.lowest_feasible_distanceLabel.Text = 'lowest_feasible_distance';
            app.lowest_feasible_distance_PRM_field = uieditfield(app.PRMTab_2, 'numeric');
            app.lowest_distance_nodeLabel = uilabel(app.PRMTab_2);
            app.lowest_distance_nodeLabel.Text = 'lowest_distance_node';
            app.lowest_distance_node_PRM_field = uieditfield(app.PRMTab_2, 'numeric');
            app.lowest_distance_valueLabel = uilabel(app.PRMTab_2);
            app.lowest_distance_valueLabel.Text = 'lowest_distance_value';
            app.lowest_distance_value_PRM_field = uieditfield(app.PRMTab_2, 'numeric');
            app.RRTTab_2 = uitab(app.TabGroup2);
            app.RRTTab_2.Title = 'RRT';
            app.First_Feasible_Path_RRT_axis = uiaxes(app.RRTTab_2);
            title(app.First_Feasible_Path_RRT_axis, 'Title')
            xlabel(app.First_Feasible_Path_RRT_axis, 'X')
            ylabel(app.First_Feasible_Path_RRT_axis, 'Y')
            zlabel(app.First_Feasible_Path_RRT_axis, 'Z')
            app.Shortest_Path_RRT_axis = uiaxes(app.RRTTab_2);
            title(app.Shortest_Path_RRT_axis, 'Title')
            xlabel(app.Shortest_Path_RRT_axis, 'X')
            ylabel(app.Shortest_Path_RRT_axis, 'Y')
            zlabel(app.Shortest_Path_RRT_axis, 'Z')
            app.Average_distance_at_nodes_RRT_axis = uiaxes(app.RRTTab_2);
            title(app.Average_distance_at_nodes_RRT_axis, 'Title')
            xlabel(app.Average_distance_at_nodes_RRT_axis, 'X')
            ylabel(app.Average_distance_at_nodes_RRT_axis, 'Y')
            zlabel(app.Average_distance_at_nodes_RRT_axis, 'Z')
            app.Average_distance_at_nodes_no_zero_RRT_axis = uiaxes(app.RRTTab_2);
            title(app.Average_distance_at_nodes_no_zero_RRT_axis, 'Title')
            xlabel(app.Average_distance_at_nodes_no_zero_RRT_axis, 'X')
            ylabel(app.Average_distance_at_nodes_no_zero_RRT_axis, 'Y')
            zlabel(app.Average_distance_at_nodes_no_zero_RRT_axis, 'Z')
            app.lowest_feasible_nodeLabel_2 = uilabel(app.RRTTab_2);
            app.lowest_feasible_nodeLabel_2.Text = 'lowest_feasible_node';
            app.lowest_feasible_node_RRT_field = uieditfield(app.RRTTab_2, 'numeric');
            app.lowest_feasible_distanceLabel_2 = uilabel(app.RRTTab_2);
            app.lowest_feasible_distanceLabel_2.Text = 'lowest_feasible_distance';
            app.lowest_feasible_distance_RRT_field = uieditfield(app.RRTTab_2, 'numeric');
            app.lowest_distance_nodeLabel_2 = uilabel(app.RRTTab_2);
            app.lowest_distance_nodeLabel_2.Text = 'lowest_distance_node';
            app.lowest_distance_node_RRT_field = uieditfield(app.RRTTab_2, 'numeric');
            app.lowest_distance_valueLabel_2 = uilabel(app.RRTTab_2);
            app.lowest_distance_valueLabel_2.Text = 'lowest_distance_value';
            app.lowest_distance_value_RRT_field = uieditfield(app.RRTTab_2, 'numeric');
            app.GATab = uitab(app.TabGroup2);
            app.GATab.Title = 'GA';
            app.avg_distance = uiaxes(app.GATab);
            title(app.avg_distance, 'Title')
            xlabel(app.avg_distance, 'X')
            ylabel(app.avg_distance, 'Y')
            zlabel(app.avg_distance, 'Z')
            app.distance_top_dot = uiaxes(app.GATab);
            title(app.distance_top_dot, 'Title')
            xlabel(app.distance_top_dot, 'X')
            ylabel(app.distance_top_dot, 'Y')
            zlabel(app.distance_top_dot, 'Z')
            app.avg_distance_top_dots = uiaxes(app.GATab);
            title(app.avg_distance_top_dots, 'Title')
            xlabel(app.avg_distance_top_dots, 'X')
            ylabel(app.avg_distance_top_dots, 'Y')
            zlabel(app.avg_distance_top_dots, 'Z')
            app.Shortest_path = uiaxes(app.GATab);
            title(app.Shortest_path, 'Title')
            xlabel(app.Shortest_path, 'X')
            ylabel(app.Shortest_path, 'Y')
            zlabel(app.Shortest_path, 'Z')
            app.shortest_distance_top_dot = uiaxes(app.GATab);
            title(app.shortest_distance_top_dot, 'Title')
            xlabel(app.shortest_distance_top_dot, 'X')
            ylabel(app.shortest_distance_top_dot, 'Y')
            zlabel(app.shortest_distance_top_dot, 'Z')
            app.avg_shortest_distance_top_dots = uiaxes(app.GATab);
            title(app.avg_shortest_distance_top_dots, 'Title')
            xlabel(app.avg_shortest_distance_top_dots, 'X')
            ylabel(app.avg_shortest_distance_top_dots, 'Y')
            zlabel(app.avg_shortest_distance_top_dots, 'Z')
            app.RoboticsTab = uitab(app.TabGroup3);
            app.RoboticsTab.Title = 'Robotics';
            app.Map2 = uiaxes(app.RoboticsTab);
            title(app.Map2, 'Title')
            xlabel(app.Map2, 'X')
            ylabel(app.Map2, 'Y')
            zlabel(app.Map2, 'Z')
            app.Map3 = uiaxes(app.RoboticsTab);
            title(app.Map3, 'Title')
            xlabel(app.Map3, 'X')
            ylabel(app.Map3, 'Y')
            zlabel(app.Map3, 'Z')
            app.Map1 = uiaxes(app.RoboticsTab);
            title(app.Map1, 'Title')
            xlabel(app.Map1, 'X')
            ylabel(app.Map1, 'Y')
            zlabel(app.Map1, 'Z')
            app.Plot_robot_path = uibutton(app.RoboticsTab, 'push');
            app.Plot_robot_path.ButtonPushedFcn = createCallbackFcn(app, @Plot_robot_pathPushed, true);
            app.Plot_robot_path.Text = 'Show live movements';
            app.SelectrobotLabel = uilabel(app.RoboticsTab);
            app.SelectrobotLabel.Text = 'Select robot';
            app.Select_robot_DropDown = uidropdown(app.RoboticsTab);
            app.Select_robot_DropDown.Items = {'Select Robot', 'diffDrive', 'unicycle', 'bicycle', 'carLike'};
            app.Select_robot_DropDown.ValueChangedFcn = createCallbackFcn(app, @Select_robot_DropDownValueChanged, true);
            app.Select_robot_DropDown.Value = 'Select Robot';
            app.SelectpathDropDownLabel = uilabel(app.RoboticsTab);
            app.SelectpathDropDownLabel.Text = 'Select path';
            app.SelectpathDropDown = uidropdown(app.RoboticsTab);
            app.SelectpathDropDown.Items = {'Select path', 'PRM', 'RRT', 'GA'};
            app.SelectpathDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectpathDropDownValueChanged, true);
            app.SelectpathDropDown.Value = 'Select path';
            app.AllrobotsperformanceTab = uitab(app.TabGroup3);
            app.AllrobotsperformanceTab.Title = 'All robots performance';
            app.All_path_comparison2 = uiaxes(app.AllrobotsperformanceTab);
            title(app.All_path_comparison2, 'Title')
            xlabel(app.All_path_comparison2, 'X')
            ylabel(app.All_path_comparison2, 'Y')
            zlabel(app.All_path_comparison2, 'Z')
            app.CalculateRobotspathsButton = uibutton(app.AllrobotsperformanceTab, 'push');
            app.CalculateRobotspathsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRobotspathsButtonPushed, true);
            app.CalculateRobotspathsButton.Text = 'Calculate Robots paths';
            app.DifferentialdrivegreenCheckBox = uicheckbox(app.AllrobotsperformanceTab);
            app.DifferentialdrivegreenCheckBox.Text = 'Differential drive (green)';
            app.DifferentialdrivegreenCheckBox.Value = true;
            app.UnicycleredCheckBox = uicheckbox(app.AllrobotsperformanceTab);
            app.UnicycleredCheckBox.Text = 'Unicycle (red)';
            app.UnicycleredCheckBox.Value = true;
            app.BicycleblueCheckBox = uicheckbox(app.AllrobotsperformanceTab);
            app.BicycleblueCheckBox.Text = 'Bicycle (blue)';
            app.BicycleblueCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AssignmentGUI_Video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end